classdef log_visualizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        SelectLogFileButton       matlab.ui.control.Button
        SelectLogDirButton        matlab.ui.control.Button
        LogFileLabel              matlab.ui.control.Label
        GenerateGraphsButton      matlab.ui.control.Button
        SaveGraphsButton          matlab.ui.control.Button
        GenerateAndSaveAllButton  matlab.ui.control.Button
        UIAxes1                   matlab.ui.control.UIAxes
        UIAxes2                   matlab.ui.control.UIAxes
        UIAxes3                   matlab.ui.control.UIAxes
        UIAxes4                   matlab.ui.control.UIAxes
        UIAxes5                   matlab.ui.control.UIAxes
        UIAxes6                   matlab.ui.control.UIAxes
    end

    % Properties that correspond to member variables
    properties (Access = private)
        % Log File Data
        timestamp;      % s
        target_pos;     % deg
        actual_pos;     % deg
        actual_torque;  % Nm
        pump_state;     % bool; CURRENTLY BROKEN
        camera_pos;     % deg

        % Helper Variables
        input_log;   % input log file, only used by FILE actions
        input_dir;   % input directory, for convenience
        output_dir;  % output directory, for convenience

        % Constants
        actuator_labels = {'Roll', 'Pitch', 'J1', 'J2', 'J3'};
        pump_labels = {'A_IN', 'B_IN', 'A_OUT', 'B_OUT'};
        camera_labels = {'Base\newline(correction)', 'Pan', 'Tilt'};
    end
    
    methods (Access = private)
        % Parse log file (.csv) and generate a set of plots
        function GeneratePlots(app, log_name)
            %% Read Log File

            log = readtable(fullfile(app.input_dir,log_name));

            % Wrap in a try-catch to ensure validity of data
            try
                % Relative Timestamp
                % TODO: save timestamps in seconds since epoch by default
                %app.timestamp = datetime(log.Time, 'InputFormat','mm:ss.SSS');
                % TODO: make timestamps start at 0 in control app
                % Adjust timestamps relative to start of log
                %app.timestamp = seconds(app.timestamp - app.timestamp(1));
                % TODO: this is hacky; really, the above lines should be used.
                % Calculate total number of seconds covered by timestamps (every 0.5s)
                latest_time_s = size(log.Time, 1) * 500 / 1000;
                app.timestamp = 0:0.5:latest_time_s-0.5;
    
                % HEBI Actuators
                app.target_pos = [log.TP_Roll_deg_ log.TP_Pitch_deg_ log.TP_J1_deg_ log.TP_J2_deg_ log.TP_J3_deg_];
                app.actual_pos = [log.PP_Roll_deg_ log.PP_Pitch_deg_ log.PP_J1_deg_ log.PP_J2_deg_ log.PP_J3_deg_];
                app.actual_torque = [log.PT_Roll_Nm_ log.PT_Pitch_Nm_ log.PT_J1_Nm_ log.PT_J2_Nm_  log.PT_J3_Nm_];
    
                % Water Pumps (SerialWater)
                app.pump_state = [log.A_IN log.B_IN log.A_OUT log.B_OUT];
    
                % Camera Actuators (SerialServo)
                app.camera_pos = [log.TP_CamBase_deg_ log.TP_CamPan_deg_ log.TP_CamTilt_deg_];

            catch
                uialert(app.UIFigure, ...
                        ["Log file contains invalid data!","Aborting..."], "Error", ...
                        'Icon', 'error')
                return
            end

            %% Configure Plots

            % Reset all plots
            cla(app.UIAxes1);
            cla(app.UIAxes2);
            cla(app.UIAxes3);
            cla(app.UIAxes4);
            cla(app.UIAxes5);
            cla(app.UIAxes6);

            % Declare common variables
            % - Timestamp (start: 0, step: 30s, end: latest time rounded to 30s)
            %   Convert the original duration to seconds
            %latest_time_s = seconds(app.timestamp(end));
            %   TODO: this is hacky; really, the above line should be used.
            %   Calculate total number of seconds covered by timestamps (every 0.5s)
            latest_time_s = size(app.timestamp, 2) * 500 / 1000;
            time_end = ceil(latest_time_s / 30) * 30;  % to nearest 30 s
            if time_end > 300
                % Large datasets
                time_step = 360;  % tick every 360s (5 min)
                time_end = ceil(time_end / 360) * 360;
            else
                % Normal
                time_step = 30;  % tick every 30s
            end
            time_range = 0:time_step:time_end;
            % - Position (start: -180 deg, step: 30 deg, end: 180 deg)
            pos_range = -180:30:180;
            pos_lims = [-180, 180];
            % - Torque (start: -50 Nm, step: 10 Nm, end: 50 Nm)
            torque_range = -50:10:50;
            torque_lims = [-50, 50];
            % - Pump State (start: 0 aka "False", step: 1, end: 1 aka "True")
            bool_range = 0:1:1;
            bool_lims = [0, 1];

            % Actuator position (target) vs. time
            ylim(app.UIAxes1, pos_lims);
            app.UIAxes1.XTick = time_range;
            app.UIAxes1.YTick = pos_range;

            % Actuator position (actual) vs. time
            ylim(app.UIAxes2, pos_lims);
            app.UIAxes2.XTick = time_range;
            app.UIAxes2.YTick = pos_range;

            % Actuator positions vs. time
            ylim(app.UIAxes3, pos_lims);
            app.UIAxes3.XTick = time_range;
            app.UIAxes3.YTick = pos_range;

            % Actuator torque (actual) vs. time
            ylim(app.UIAxes4, torque_lims);
            app.UIAxes4.XTick = time_range;
            app.UIAxes4.YTick = torque_range;

            % Pump state vs. time
            ylim(app.UIAxes5, bool_lims);
            app.UIAxes5.XTick = time_range;
            app.UIAxes5.YTick = bool_range;

            % Camera position vs. time
            ylim(app.UIAxes6, pos_lims);
            app.UIAxes6.XTick = time_range;
            app.UIAxes6.YTick = pos_range;
            
            %% Plot Data

            % Allow switching between mechanical- and software-model naming
            % conventions in the actuator-related plots' legend labels
            answer = questdlg('Which naming convention should be used for actuator legend labels?', ...
	                          'Legend Labeling', ...
	                          'Mechanical','Software', ...
                              'Software');  % opts, here just specify default
            % Handle response
            switch answer
                case 'Mechanical'
                    % According to the mechanical model
                    app.actuator_labels = {'J1', 'J2', 'J3', 'J4', 'J5'};
                case 'Software'
                    % According to the software model
                    app.actuator_labels = {'Roll', 'Pitch', 'J1', 'J2', 'J3'};
            end

            % Actuator position (target) vs. time
            plot(app.UIAxes1, app.timestamp, app.target_pos);
            title(app.UIAxes1, {'Actuator Target Position','over Time'});
            legend(app.UIAxes1, app.actuator_labels, ...
                   'Location', 'northeastoutside');
            xlabel(app.UIAxes1, 'Time (s)');
            ylabel(app.UIAxes1, 'Position (deg)');

            % Actuator position (actual) vs. time
            plot(app.UIAxes2, app.timestamp, app.actual_pos);
            title(app.UIAxes2, {'Actuator Actual Position','over Time'});
            legend(app.UIAxes2, app.actuator_labels, ...
                   'Location', 'northeastoutside');
            xlabel(app.UIAxes2, 'Time (s)');
            ylabel(app.UIAxes2, 'Position (deg)');

            % Actuator positions vs. time
            hold(app.UIAxes3, 'on');
            color_order = get(app.UIAxes3, 'ColorOrder');  % get default color order
            for i = 1:size(app.target_pos, 2)
                plot(app.UIAxes3, app.timestamp, app.target_pos(:,i), ...
                     '--', 'Color', color_order(i, :));
                plot(app.UIAxes3, app.timestamp, app.actual_pos(:,i), ...
                     '-', 'Color', color_order(i, :));
            end
            hold(app.UIAxes3, 'off');
            title(app.UIAxes3, {'Actuator Target vs. Actual Position','over Time'});
            [~, lhandle] = legend(app.UIAxes3, app.actuator_labels, ...
                                  'Location', 'northeastoutside');
            xlabel(app.UIAxes3, 'Time (s)');
            ylabel(app.UIAxes3, 'Position (deg)');
            % Combine label lines for cleaner legend
            % NOTE: labels and lines are stored in first 1/3 and last 2/3 of
            %       lhandle, respectively. Therefore, we want to start the loop
            %       at end of first 1/3 + 1.
            i_color = 1;
            for i = ((size(lhandle,1) / 3) + 1):2:size(lhandle,1)
                % Set line types
                lhandle(i).LineStyle = '--';   % target
                lhandle(i+1).LineStyle = '-';  % actual
                % Set line colors
                lhandle(i).Color = color_order(i_color, :);
                lhandle(i+1).Color = color_order(i_color, :);
                % Set line locations (relative to their place in the legend)
                lhandle(i).YData = lhandle(i).YData + 0.04;    % move dashed up
                lhandle(i+1).XData = lhandle(i).XData;         % spawn solid, same length
                lhandle(i+1).YData = lhandle(i).YData - 0.08;  % move solid down
                % Increment color index
                i_color = i_color + 1;
            end

            % Actuator torque (actual) vs. time
            hold(app.UIAxes4, 'on');
            plot(app.UIAxes4, app.timestamp, app.actual_torque);
            % - Continuous torque limit for HEBI X8-16 is 16 Nm
            yline(app.UIAxes4, 16, '--r');
            yline(app.UIAxes4, -16, '--r');
            % - Peak torque limit for HEBI X8-16 is 38 Nm
            yline(app.UIAxes4, 38, '-r');
            yline(app.UIAxes4, -38, '-r');
            hold(app.UIAxes4, 'off');
            title(app.UIAxes4, {'Actuator Actual Torque','over Time'});
            legend(app.UIAxes4, app.actuator_labels, ...
                   'Location', 'northeastoutside');
            xlabel(app.UIAxes4, 'Time (s)');
            ylabel(app.UIAxes4, 'Torque (Nm)');

            % Pump state vs. time
            plot(app.UIAxes5, app.timestamp, app.pump_state);
            title(app.UIAxes5, {'Pump State','over Time'});
            legend(app.UIAxes5, app.pump_labels, ...
                   'Location', 'northeastoutside');
            xlabel(app.UIAxes5, 'Time (s)');
            ylabel(app.UIAxes5, 'Activity (on/off)');

            % Camera position vs. time
            plot(app.UIAxes6, app.timestamp, app.camera_pos);
            title(app.UIAxes6, {'Camera Actual Position','over Time'});
            legend(app.UIAxes6, app.camera_labels, ...
                   'Location', 'northeastoutside');
            xlabel(app.UIAxes6, 'Time (s)');
            ylabel(app.UIAxes6, 'Position (deg)');
        end
        
        % Save the active set of plots to individual PDFs
        function SavePlots(app, log_name)
            % Allow user to specify output directory
            if isempty(app.output_dir) || isnumeric(app.output_dir)
                app.output_dir = uigetdir(app.input_dir, 'Select output directory');
            end

            % If user exited dialog box without selecting a directory, return
            if app.output_dir == 0
                return;
            end

            output_subdir = fullfile(app.output_dir, log_name);
            mkdir(output_subdir);

            % Save plots
            exportgraphics(app.UIAxes1, ...
                           fullfile(output_subdir, '1_target-pos_vs_time.pdf'), ...
                           'Resolution', 600);
            exportgraphics(app.UIAxes2, ...
                           fullfile(output_subdir, '2_actual-pos_vs_time.pdf'), ...
                           'Resolution', 600);
            exportgraphics(app.UIAxes3, ...
                           fullfile(output_subdir, '3_target-and-actual_vs_time.pdf'), ...
                           'Resolution', 600);
            exportgraphics(app.UIAxes4, ...
                           fullfile(output_subdir, '4_actual-trq_vs_time.pdf'), ...
                           'Resolution', 600);
            exportgraphics(app.UIAxes5, ...
                           fullfile(output_subdir, '5_pump-state_vs_time.pdf'), ...
                           'Resolution', 600);
            exportgraphics(app.UIAxes6, ...
                           fullfile(output_subdir, '6_cam-pos_vs_time.pdf'), ...
                           'Resolution', 600);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % We want MATLAB-modified column titles, so disable this warning
            warning('off', 'MATLAB:table:ModifiedAndSavedVarnames');
        end

        % Button pushed function: SelectLogFileButton
        function SelectLogFileButtonPushed(app, event)
            % Get log file and save off relevant info
            [file, path] = uigetfile('*.csv', 'Select input log file (.csv)', app.input_dir);
            if file == 0
                % If user exited dialog box without selecting a file, return
                return;
            end

            app.input_log = file;
            app.input_dir = path;

            % Update UI elements
            app.LogFileLabel.Text = app.input_log;
            app.GenerateGraphsButton.Enable = 'on';
            app.SaveGraphsButton.Enable = 'off';
            app.GenerateAndSaveAllButton.Enable = 'off';  % unused by FILE actions
        end

        % Button pushed function: SelectLogDirButton
        function SelectLogDirButtonPushed(app, event)
            % Get log directory and save off relevant info
            path = uigetdir(app.input_dir, 'Select directory with input log files (.csv)');
            if path == 0
                % If user exited dialog box without selecting a directory, return
                return;
            end

            app.input_dir = path;

            % Update UI elements
            app.LogFileLabel.Text = app.input_dir;
            app.GenerateGraphsButton.Enable = 'off';  % unused by DIR actions
            app.SaveGraphsButton.Enable = 'off';      % unused by DIR actions
            app.GenerateAndSaveAllButton.Enable = 'on';
        end

        % Button pushed function: GenerateGraphsButton
        function GenerateGraphsButtonPushed(app, event)
            % Read log file and plot data
            app.GeneratePlots(app.input_log);

            % Update UI Elements
            app.GenerateGraphsButton.Enable = 'off';
            app.SaveGraphsButton.Enable = 'on';
        end

        % Button pushed function: SaveGraphsButton
        function SaveGraphsButtonPushed(app, event)
            % Temporarily disable other inputs
            app.SelectLogFileButton.Enable = 'off';
            app.SelectLogDirButton.Enable = 'off';
            app.SaveGraphsButton.Enable = 'off';
            
            % Save plots to PDFs
            app.SavePlots(app.input_log);

            uialert(app.UIFigure, ...
                    ["Graphs have been saved to the following directory:"; ...
                     app.output_dir], "Done", ...
                   'Icon', 'success')

            % Re-enable UI elements
            app.SelectLogFileButton.Enable = 'on';
            app.SelectLogDirButton.Enable = 'on';
            app.SaveGraphsButton.Enable = 'on';
        end

        % Button pushed function: GenerateAndSaveAllButton
        function GenerateAndSaveAllButtonPushed(app, event)
            % Temporarily disable other inputs
            app.SelectLogFileButton.Enable = 'off';
            app.SelectLogDirButton.Enable = 'off';
            app.GenerateAndSaveAllButton.Enable = 'off';
            
            % Iterate through all log files in directory
            log_names = dir(strcat(app.input_dir, '/*.csv'));
            num_logs = size(log_names,1);
            progress_incr = round(1 / size(log_names,1), 2);

            d = uiprogressdlg(app.UIFigure, 'Title', 'Saving graphs');

            for i = 1:num_logs
                curr_log = log_names(i).name;
                d.Message = "Processing set " + i + "/" + num_logs + "..." + newline + "(" + curr_log + ")";

                app.GeneratePlots(curr_log);
                app.SavePlots(curr_log);

                d.Value = d.Value + progress_incr; 
                pause(0.5)
            end

            close(d)

            uialert(app.UIFigure, ...
                    ["Graphs have been saved to the following directory:"; ...
                     app.output_dir], "Done", ...
                   'Icon', 'success')

            % Update UI elements
            app.SelectLogFileButton.Enable = 'on';
            app.SelectLogDirButton.Enable = 'on';
            app.GenerateAndSaveAllButton.Enable = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes6 = uiaxes(app.GridLayout);
            title(app.UIAxes6, '...')
            xlabel(app.UIAxes6, '...')
            ylabel(app.UIAxes6, '...')
            zlabel(app.UIAxes6, '...')
            app.UIAxes5 = uiaxes(app.GridLayout);
            title(app.UIAxes5, '...')
            xlabel(app.UIAxes5, '...')
            ylabel(app.UIAxes5, '...')
            zlabel(app.UIAxes5, '...')
            app.UIAxes4 = uiaxes(app.GridLayout);
            title(app.UIAxes4, '...')
            xlabel(app.UIAxes4, '...')
            ylabel(app.UIAxes4, '...')
            zlabel(app.UIAxes4, '...')
            app.UIAxes3 = uiaxes(app.GridLayout);
            title(app.UIAxes3, '...')
            xlabel(app.UIAxes3, '...')
            ylabel(app.UIAxes3, '...')
            zlabel(app.UIAxes3, '...')
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, '...')
            xlabel(app.UIAxes2, '...')
            ylabel(app.UIAxes2, '...')
            zlabel(app.UIAxes2, '...')
            app.UIAxes1 = uiaxes(app.GridLayout);
            title(app.UIAxes1, '...')
            xlabel(app.UIAxes1, '...')
            ylabel(app.UIAxes1, '...')
            zlabel(app.UIAxes1, '...')
            app.GenerateAndSaveAllButton = uibutton(app.GridLayout, 'push');
            app.GenerateAndSaveAllButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateAndSaveAllButtonPushed, true);
            app.GenerateAndSaveAllButton.Enable = 'off';
            app.GenerateAndSaveAllButton.Text = 'Generate and Save All';
            app.SaveGraphsButton = uibutton(app.GridLayout, 'push');
            app.SaveGraphsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveGraphsButtonPushed, true);
            app.SaveGraphsButton.Enable = 'off';
            app.SaveGraphsButton.Text = 'Save Graphs';
            app.GenerateGraphsButton = uibutton(app.GridLayout, 'push');
            app.GenerateGraphsButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateGraphsButtonPushed, true);
            app.GenerateGraphsButton.Enable = 'off';
            app.GenerateGraphsButton.Text = 'Generate Graphs';
            app.LogFileLabel = uilabel(app.GridLayout);
            app.LogFileLabel.Text = '...';
            app.SelectLogDirButton = uibutton(app.GridLayout, 'push');
            app.SelectLogDirButton.ButtonPushedFcn = createCallbackFcn(app, @SelectLogDirButtonPushed, true);
            app.SelectLogDirButton.Text = 'Select Log Folder';
            app.SelectLogFileButton = uibutton(app.GridLayout, 'push');
            app.SelectLogFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectLogFileButtonPushed, true);
            app.SelectLogFileButton.Text = 'Select Log File';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = log_visualizer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end